classdef DSP_Assignment_6_BryanZhen < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        GridLayout          matlab.ui.container.GridLayout
        fdEditField         matlab.ui.control.NumericEditField
        fdEditFieldLabel    matlab.ui.control.Label
        phiEditField        matlab.ui.control.NumericEditField
        phiEditFieldLabel   matlab.ui.control.Label
        A0EditField         matlab.ui.control.NumericEditField
        A0EditFieldLabel    matlab.ui.control.Label
        ApplyFiltersButton  matlab.ui.control.Button
        supp_axes           matlab.ui.control.UIAxes
        conv_axes           matlab.ui.control.UIAxes
        noisy_axes          matlab.ui.control.UIAxes
        og_axes             matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        A0;
        fd;
        phi;

        %signals and time
        t;
        original_sig;
        noisy_sig;
        convfilt_sig;
        newfilt_sig;


    end
    
    methods (Access = private)
        
        function [t, s_n, x_n, ecgFilt1, ecgFilt2]  = runfilter(app,A0,fd,phi) %paste in demo code here to get both filtered signals

            % Filtering parameters.
            BW = 0.8;   % Notch bandwidth [Hz].
            M = 10;     % Number of initial samples to consider for the transient
            % suppression technique.


            % ecgOriginal   Original (clean) ECG signal (given by Eq. 3).
            % fs            Sampling frequency [Hz].
            load ecg;

            s_n = ecgOriginal;              % To follow paper's notation.

            % Original data consists of 10 s recording.
            % For simplicity, we will consider only the first 5 s.
            s_n = s_n(1:end/2);


            %% Calculate important parameters.

            N = numel(s_n);                 % Number of samples.
            Ts = 1/fs;                      % Sampling period [s].
            t = linspace(0, (N-1)*Ts, N);   % Time vector [s].

            w0 = 2 * pi * (fd/fs);          % Notch frequency [rad/s].
            omega = 2 * pi * (BW/fs);       % Bandwidth [rad/s].


            %% Contaminate original signal.

            % Create noise, i.e. AC interference (Eq. 4).
            d_n = A0 * sin((2 * pi * fd) .* t + deg2rad(phi));

            % Add the original signal with the noise (Eq, 3).
            x_n = s_n + d_n;


            %% Filtering process 1 (conventional).

            % 1. Calculate a1 and a2 coefficients using Eq. 2.
            a1 = (2 * cos(w0)) / (1 + tan(omega/2));
            a2 = (1 - tan(omega/2)) / (1 + tan(omega/2));

            % 2. Choose arbitrary initial conditions (x[-1], x[-2], y[-1], y[-2]).
            x_1 = 0;    % x[-1].
            x_2 = 0;	% x[-2].
            y_1 = 0;	% y[-1].
            y_2 = 0;	% y[-2].

            % 3. From n = 0 to N,  calculate the output, given by Eq. 5.

            % Manually calculate the first two samples (for sake of clarity).
            y_n(1) = 0.5 * ((1 + a2)* x_n(1) - 2*a1*x_1    + (1 + a2)*x_2) + (a1*y_1)    - (a2*(y_2));
            y_n(2) = 0.5 * ((1 + a2)* x_n(2) - 2*a1*x_n(1) + (1 + a2)*x_1) + (a1*y_n(1)) - (a2*y_1);

            for n = 3:N
                y_n(n) = 0.5 * ((1 + a2)* x_n(1,n) - 2*a1*x_n(n-1) + (1 + a2)*x_n(n-2)) + (a1*y_n(n-1)) - (a2*y_n(n-2));
            end

            ecgFilt1 = y_n;


            %% Filtering process 2 (notch filtering with transient state suppression).
            y_n = ecgTransientSuppression(x_n, fs, fd, BW, M);
            ecgFilt2 = y_n;        
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

        end

        % Value changed function: A0EditField
        function setA0(app, event)
            app.A0 = app.A0EditField.Value;
            
        end

        % Value changed function: phiEditField
        function setphi(app, event)
            app.phi = app.phiEditField.Value;
            
        end

        % Value changed function: fdEditField
        function setfd(app, event)
            app.fd = app.fdEditField.Value;
            
        end

        % Button pushed function: ApplyFiltersButton
        function filter_ecg(app, event)
            A0  = app.A0;
            fd  = app.fd;
            phi = app.phi;
            [app.t,app.original_sig,app.noisy_sig,app.convfilt_sig,app.newfilt_sig] = runfilter(app,A0,fd,phi);

            plot(app.og_axes,app.original_sig)
            plot(app.noisy_axes,app.noisy_sig)
            plot(app.conv_axes,app.convfilt_sig)
            plot(app.supp_axes,app.newfilt_sig)

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.og_axes = uiaxes(app.GridLayout);
            title(app.og_axes, 'original ECG')
            xlabel(app.og_axes, 'Samples')
            ylabel(app.og_axes, 'Amplitude [\muV]')
            zlabel(app.og_axes, 'Z')
            app.noisy_axes = uiaxes(app.GridLayout);
            title(app.noisy_axes, 'noisy ECG')
            xlabel(app.noisy_axes, 'Samples')
            ylabel(app.noisy_axes, 'Amplitude [\muV]')
            zlabel(app.noisy_axes, 'Z')
            app.conv_axes = uiaxes(app.GridLayout);
            title(app.conv_axes, 'filtered ECG using conventional method')
            xlabel(app.conv_axes, 'Samples')
            ylabel(app.conv_axes, 'Amplitude [\muV]')
            zlabel(app.conv_axes, 'Z')
            app.supp_axes = uiaxes(app.GridLayout);
            title(app.supp_axes, 'filtered ECG using the transient supression method')
            xlabel(app.supp_axes, 'Samples')
            ylabel(app.supp_axes, 'Amplitude [\muV]')
            zlabel(app.supp_axes, 'Z')
            app.ApplyFiltersButton = uibutton(app.GridLayout, 'push');
            app.ApplyFiltersButton.ButtonPushedFcn = createCallbackFcn(app, @filter_ecg, true);
            app.ApplyFiltersButton.Text = 'Apply Filters';
            app.A0EditFieldLabel = uilabel(app.GridLayout);
            app.A0EditFieldLabel.Text = 'A0';
            app.A0EditField = uieditfield(app.GridLayout, 'numeric');
            app.A0EditField.ValueChangedFcn = createCallbackFcn(app, @setA0, true);
            app.phiEditFieldLabel = uilabel(app.GridLayout);
            app.phiEditFieldLabel.Text = 'phi';
            app.phiEditField = uieditfield(app.GridLayout, 'numeric');
            app.phiEditField.ValueChangedFcn = createCallbackFcn(app, @setphi, true);
            app.fdEditFieldLabel = uilabel(app.GridLayout);
            app.fdEditFieldLabel.Text = 'fd';
            app.fdEditField = uieditfield(app.GridLayout, 'numeric');
            app.fdEditField.ValueChangedFcn = createCallbackFcn(app, @setfd, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DSP_Assignment_6_BryanZhen

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end